classdef GenshinDamageGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        Tab                          matlab.ui.container.Tab
        Button                       matlab.ui.control.Button
        Panel_4                      matlab.ui.container.Panel
        Spinner_2                    matlab.ui.control.Spinner
        Spinner_2Label               matlab.ui.control.Label
        Spinner                      matlab.ui.control.Spinner
        Label_8                      matlab.ui.control.Label
        Panel_3                      matlab.ui.container.Panel
        EditField_6                  matlab.ui.control.NumericEditField
        Label_7                      matlab.ui.control.Label
        EditField_5                  matlab.ui.control.NumericEditField
        Label_6                      matlab.ui.control.Label
        EditField_4                  matlab.ui.control.NumericEditField
        Label_5                      matlab.ui.control.Label
        EditField_3                  matlab.ui.control.NumericEditField
        Label_4                      matlab.ui.control.Label
        EditField_2                  matlab.ui.control.NumericEditField
        Label_3                      matlab.ui.control.Label
        EditField                    matlab.ui.control.NumericEditField
        Label_2                      matlab.ui.control.Label
        Panel_2                      matlab.ui.container.Panel
        DropDown_3                   matlab.ui.control.DropDown
        DropDown_3Label              matlab.ui.control.Label
        DropDown_2                   matlab.ui.control.DropDown
        DropDown_2Label              matlab.ui.control.Label
        DropDown                     matlab.ui.control.DropDown
        Label                        matlab.ui.control.Label
        Panel                        matlab.ui.container.Panel
        LoadPresetButton             matlab.ui.control.Button
        SavePresetButton             matlab.ui.control.Button
        PresetSelectorDropDown       matlab.ui.control.DropDown
        PresetSelectorDropDownLabel  matlab.ui.control.Label
        WeaponSelectorDropDown       matlab.ui.control.DropDown
        WeaponSelectorDropDownLabel  matlab.ui.control.Label
        AvatarSelectorDropDown       matlab.ui.control.DropDown
        AvatarSelectorDropDownLabel  matlab.ui.control.Label
        Tab_2                        matlab.ui.container.Tab
        Panel_5                      matlab.ui.container.Panel
        FinalDEFLabel                matlab.ui.control.Label
        FinalEMLabel                 matlab.ui.control.Label
        FinalCritDMGLabel            matlab.ui.control.Label
        FinalCritRateLabel           matlab.ui.control.Label
        FinalATKLabel                matlab.ui.control.Label
        FinalHPLabel                 matlab.ui.control.Label
    end

    
    methods (Access = private)
        
        function startupFcn(app)
            % 读取数据（放在项目根目录或data文件夹）
            app.weapons = readtable('data/weapons.csv');
            app.RoleSelector.Items = {'哥伦比娅','芙宁娜','胡桃'};
            app.RoleSelector.Value = '哥伦比娅';
    
            % 默认加载哥伦比娅
            app.switchRole('哥伦比娅');
    
            % 加载预设（如果存在）
            app.loadPresets();
    
            % 填充武器下拉菜单
            app.WeaponDropDown.Items = app.weapons.Name;
            app.WeaponDropDown.Value = '帷间夜曲';  % 默认你的武器
    
            % 初始化默认参数
            app.HPPercentEditField.Value      = 145.8;   % 例如花+沙+杯
            app.CritRateEditField.Value       = 70;
            app.CritDMGEditField.Value        = 200;
            app.HydroDMGEditField.Value       = 46.6;
            app.EMEditField.Value             = 120;
            app.ResShredEditField.Value       = 30;
            app.TalentLevelSpinner.Value      = 10;
            app.ConstellationSpinner.Value    = 0;
        end
        
        function results = RoleSelectorValueChanged(app, ~)
            selectedRole = app.RoleSelector.Value;
            app.switchRole(selectedRole);
        end
        
        function switchRole(app, roleName)
            % 动态切换背景
            bgFile = ['images/' roleName '_BG.png'];
            if exist(bgFile, 'file')
                app.BackgroundImage.ImageSource = bgFile;
            end

            % 加载对应角色的天赋和人物数据
            app.talent = readtable(['data/talents_' roleName '.csv']);
            app.characterData = readtable(['data/characters_' roleName '.csv']);
    
            % 刷新预设列表
            app.PresetSelector.Items = app.getPresetList(roleName);
        end
        
        function CalculateButtonPushed(app, ~) %#ok<REDEF>
            % 收集所有输入 → build struct
            build = app.collectCurrentBuild();   % 新增方法，收集所有EditField
    
            talentLvl = app.TalentLevelSpinner.Value;
            cLvl = app.ConstellationSpinner.Value;
    
            enemy = struct('Level',90,'Res',0.10,'DefReduct',0);
    
            [totalDMG, dps, breakdown] = simulateColumbinaDPS(app, build, enemy, talentLvl, cLvl);
    
            % 更新实战面板（重点！）
            app.updateCombatPanel(build);        % 新增方法，显示最终HP/ATK等
    
            % 更新图表
            app.updateBarChart(breakdown);
            app.updateLineChart(build, talentLvl, cLvl);
    
            app.FinalDPSLabel.Text = sprintf('%.0f', dps);           
        end
        
        function updateCombatPanel(app, build)
            finalHP = build.MaxHP;           % 来自 simulate 函数计算后的 build
            finalATK = build.ATK;
            finalCritRate = build.CritRate * 100;
            finalCritDMG = build.CritDMG * 100;
    
            app.FinalHPLabel.Text = sprintf('%.0f', finalHP);
            app.FinalATKLabel.Text = sprintf('%.0f', finalATK);
            app.FinalCritLabel.Text = sprintf('%.1f%% / %.1f%%', finalCritRate, finalCritDMG);
            % ... 其他属性同理
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SavePresetButton
        function SavePresetButtonPushed(app, event)
            presetName = inputdlg('请输入预设名称（如 金剧团满配）','保存预设');
            if isempty(presetName), return; end
            build = app.collectCurrentBuild();
            writetable(struct2table(build), ['data/presets/' app.RoleSelector.Value '_' presetName{1} '.csv']);
            app.loadPresets();   % 刷新列表
        end

        % Button pushed function: LoadPresetButton
        function LoadPresetButtonPushed(app, event)
            selected = app.PresetSelector.Value;
            file = ['data/presets/' app.RoleSelector.Value '_' selected '.csv'];
            if exist(file,'file')
                buildTable = readtable(file);
                app.applyBuildToUI(table2struct(buildTable));  % 把数值填回EditField
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '配置面板';
            app.Panel = uipanel(app.Tab);
            app.Panel.Title = '角色+武器+预设选择';
            app.AvatarSelectorDropDownLabel = uilabel(app.Panel);
            app.AvatarSelectorDropDownLabel.Text = 'AvatarSelector';
            app.AvatarSelectorDropDown = uidropdown(app.Panel);
            app.AvatarSelectorDropDown.Items = {'哥伦比娅', '芙宁娜', '胡桃'};
            app.AvatarSelectorDropDown.Value = '哥伦比娅';
            app.WeaponSelectorDropDownLabel = uilabel(app.Panel);
            app.WeaponSelectorDropDownLabel.Text = 'WeaponSelector';
            app.WeaponSelectorDropDown = uidropdown(app.Panel);
            app.PresetSelectorDropDownLabel = uilabel(app.Panel);
            app.PresetSelectorDropDownLabel.Text = 'PresetSelector';
            app.PresetSelectorDropDown = uidropdown(app.Panel);
            app.SavePresetButton = uibutton(app.Panel, 'push');
            app.SavePresetButton.ButtonPushedFcn = createCallbackFcn(app, @SavePresetButtonPushed, true);
            app.SavePresetButton.Text = 'Save Preset';
            app.LoadPresetButton = uibutton(app.Panel, 'push');
            app.LoadPresetButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPresetButtonPushed, true);
            app.LoadPresetButton.Text = 'Load Preset';
            app.Panel_2 = uipanel(app.Tab);
            app.Panel_2.Title = '主词条';
            app.Label = uilabel(app.Panel_2);
            app.Label.Text = '时之沙';
            app.DropDown = uidropdown(app.Panel_2);
            app.DropDown.Items = {'生命值百分比', '攻击力百分比', '防御力百分比', '元素充能效率', '元素精通'};
            app.DropDown.Value = '生命值百分比';
            app.DropDown_2Label = uilabel(app.Panel_2);
            app.DropDown_2Label.Text = '空之杯';
            app.DropDown_2 = uidropdown(app.Panel_2);
            app.DropDown_2.Items = {'生命值百分比', '攻击力百分比', '防御力百分比', '元素充能效率', '元素精通', '火元素伤害加成', '水元素伤害加成', '雷元素伤害加成', '冰元素伤害加成', '岩元素伤害加成', '草元素伤害加成', '火元素伤害加成', '物理伤害加成'};
            app.DropDown_2.Value = '生命值百分比';
            app.DropDown_3Label = uilabel(app.Panel_2);
            app.DropDown_3Label.Text = '理之冠';
            app.DropDown_3 = uidropdown(app.Panel_2);
            app.DropDown_3.Items = {'生命值百分比', '攻击力百分比', '防御力百分比', '元素精通', '暴击伤害', '暴击率'};
            app.DropDown_3.Value = '生命值百分比';
            app.Panel_3 = uipanel(app.Tab);
            app.Panel_3.Title = '副词条总情况';
            app.Label_2 = uilabel(app.Panel_3);
            app.Label_2.Text = '生命值百分比';
            app.EditField = uieditfield(app.Panel_3, 'numeric');
            app.Label_3 = uilabel(app.Panel_3);
            app.Label_3.Text = '攻击力百分比';
            app.EditField_2 = uieditfield(app.Panel_3, 'numeric');
            app.Label_4 = uilabel(app.Panel_3);
            app.Label_4.Text = '防御力百分比';
            app.EditField_3 = uieditfield(app.Panel_3, 'numeric');
            app.Label_5 = uilabel(app.Panel_3);
            app.Label_5.Text = '暴击伤害';
            app.EditField_4 = uieditfield(app.Panel_3, 'numeric');
            app.Label_6 = uilabel(app.Panel_3);
            app.Label_6.Text = '暴击率';
            app.EditField_5 = uieditfield(app.Panel_3, 'numeric');
            app.Label_7 = uilabel(app.Panel_3);
            app.Label_7.Text = '元素精通';
            app.EditField_6 = uieditfield(app.Panel_3, 'numeric');
            app.Panel_4 = uipanel(app.Tab);
            app.Panel_4.Title = '高级选项';
            app.Label_8 = uilabel(app.Panel_4);
            app.Label_8.Text = '天赋等级';
            app.Spinner = uispinner(app.Panel_4);
            app.Spinner.Limits = [1 15];
            app.Spinner.Value = 1;
            app.Spinner_2Label = uilabel(app.Panel_4);
            app.Spinner_2Label.Text = '命座等级';
            app.Spinner_2 = uispinner(app.Panel_4);
            app.Spinner_2.Limits = [0 6];
            app.Button = uibutton(app.Tab, 'push');
            app.Button.Text = '计算伤害';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '伤害可视化';
            app.Panel_5 = uipanel(app.Tab_2);
            app.Panel_5.Title = '实战面板';
            app.FinalHPLabel = uilabel(app.Panel_5);
            app.FinalHPLabel.Text = 'FinalHP';
            app.FinalATKLabel = uilabel(app.Panel_5);
            app.FinalATKLabel.Text = 'FinalATK';
            app.FinalCritRateLabel = uilabel(app.Panel_5);
            app.FinalCritRateLabel.Text = 'FinalCritRate';
            app.FinalCritDMGLabel = uilabel(app.Panel_5);
            app.FinalCritDMGLabel.Text = 'FinalCritDMG';
            app.FinalEMLabel = uilabel(app.Panel_5);
            app.FinalEMLabel.Text = 'FinalEM';
            app.FinalDEFLabel = uilabel(app.Panel_5);
            app.FinalDEFLabel.Text = 'FinalDEF';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GenshinDamageGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end